classdef lstmapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        EditField_2        matlab.ui.control.EditField
        EditField          matlab.ui.control.NumericEditField
        Label              matlab.ui.control.Label
        socEditField       matlab.ui.control.NumericEditField
        socEditFieldLabel  matlab.ui.control.Label
        sohEditField       matlab.ui.control.NumericEditField
        sohEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Timer_id;        % 定时器id
        lstm_model_B5; 
        mode;  % 初始化为 0，表示初始状态
        voltage_load = [];  % 存储电压数据的数组
        current_load = [];  % 存储电流数据的数组
        voltage_power = [];
        current_power = [];
        temprature = [];    % 存储温度数据的数组
        time = [];          % 存储时间数据的数组
        start_time;         % 记录开始时间
        scope;
        cycle;
        capacity_1;
        capacity_2;
        capacity_3;
        capacity_4;
        capacity_5;
    end
    
    methods (Access = private)
        % 定时器初始化，重复模式
        function timer_init(app)
            
            try 
                assemblyCheck = NET.addAssembly('Ivi.Visa');
            catch
                error('Error loading .NET assembly Ivi.Visa');
            end
            resourceString5 = 'ASRL5::INSTR'; % USB-TMC (Test and Measurement Class)
            s = serialport("COM4", 9600); % arduino
            %Opening VISA session to the instrument
            app.scope = Ivi.Visa.GlobalResourceManager.Open(resourceString5);
            app.scope.Clear()

            load('lstm_model_B5.mat');
            app.lstm_model_B5 = lstm_model_B5;  % 确保存储到属性
            app.cycle = 1
            app.capacity_1 = rand() *  1.8
            app.capacity_2 = rand() *  1.8
            app.capacity_3 = rand() *  1.7
            app.capacity_4 = rand() *  1.7
            app.capacity_5 = rand() *  1.6

            app.Timer_id = timer;
            app.Timer_id.StartDelay = 0.01; % 开启的延时生效时间
            app.Timer_id.Period = 1.0;% 周期
            app.Timer_id.ExecutionMode = 'fixedSpacing';
            %app.Timer_id.TimerFcn = @(~, ~) timer_handler(app);
            %app.Timer_id.TimerFcn = @(src, event) timer_handler(app, src, event, lstm_model_B5);
            app.Timer_id.TimerFcn = @(src, event) timer_handler(app, src, event, app.lstm_model_B5, app.scope, s);
            app.start_time = tic;
            WRVI_load(app.scope,2);
            %WRVI_power(app.scope, 4.2, 1);
            pause(2);


        end
    
        % 定时器启动
        function timer_start(app)
            start(app.Timer_id);
            app.mode = 0  % 设置初始模式
        end
    
        % 定时停止
        function timer_stop(app)
            stop(app.Timer_id);
        end
    
        % 删除定时器
        function timer_delete(app)
            delete(app.Timer_id);
        end
    
        %定时器回调
        function timer_handler(app, ~, ~, lstm_model_B5, scope, s)
            %执行定时器任务
            if app.mode==0
                disp('Start discharging');
                [v_l, i_l] = RDVI_load(scope);
                temp = Temp(s);
                elapsed_time = toc(app.start_time);
                app.voltage_load = [app.voltage_load; str2double(v_l)];
                soc = ((str2double(v_l)-2.7)/(4.2-2.7))*100;
                app.socEditField.Value = soc;
                app.current_load = [app.current_load; -1.*str2double(i_l)];
                app.temprature = [app.temprature; str2double(temp)];
                app.time = [app.time; toc(app.start_time);];
                data_for_predict = [app.cycle, app.capacity_1, app.capacity_2, app.capacity_3 ,app.capacity_4, app.capacity_5, str2double(temp), str2double(i_l), str2double(v_l), toc(app.start_time);]
                predicted_soh = predict(lstm_model_B5, data_for_predict')
                %predicted_soh_m = [predicted_soh_m; predicted_soh];
                predicted_soh = double(predicted_soh);
                app.sohEditField.Value = predicted_soh;
                app.EditField.Value = str2double(temp);
                if str2double(temp) > 26
                    app.EditField_2.Value = 'Warning: High Temperature';
                else
                    app.EditField_2.Value = '';  % 清除警告
                end
                if str2double(v_l) <= 2.7000
                    capacity_value = str2double(i_l).*app.time(end)./3600;
                    app.cycle = app.cycle + 1;
                    capacity = ones(size(app.voltage_load)) * capacity_value;
                    disp('discharging is complete');
                    off_ch1(scope);
                    off_ch2(scope);
                    app.mode = 1;
                    app.start_time = tic;
                    WRVI_power(scope, 4.2, 1);
                    pause(2);

                end
            
            end
            if app.mode==1
                disp('Start charging');
                [v_p, i_p] = RDVI_power(scope);
                temp = Temp(s);
                elapsed_time = toc(app.start_time);
                app.voltage_power = [app.voltage_power; str2double(v_p)];
                app.current_power = [app.current_power; str2double(i_p)];
                app.temprature = [app.temprature; str2double(temp)];
                app.time = [app.time; toc(app.start_time);];
                if str2double(temp) > 26.4
                    app.EditField_2.Value = 'Warning: High Temperature';
                else
                    app.EditField_2.Value = '';  % 清除警告
                end
                if str2double(i_p) <= 0.0200
                    disp('charging is complete');
                    off_ch1(scope);
                    off_ch2(scope);
                    app.mode = 0;
                    app.start_time = tic;
                    WRVI_load(scope,2);
                    pause(2);

                end
            end       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            timer_init(app);
            timer_start(app);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sohEditFieldLabel = uilabel(app.UIFigure);
            app.sohEditFieldLabel.Text = 'soh';
            app.sohEditField = uieditfield(app.UIFigure, 'numeric');
            app.socEditFieldLabel = uilabel(app.UIFigure);
            app.socEditFieldLabel.Text = 'soc';
            app.socEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '溫度';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lstmapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end