classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image             matlab.ui.control.Image
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        dischargeButton   matlab.ui.control.Button
        chargeButton      matlab.ui.control.Button
        Label             matlab.ui.control.Label
        temperatureLabel  matlab.ui.control.Label
        SOCLabel          matlab.ui.control.Label
        SOHLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        end

        % Button pushed function: chargeButton
        function chargeButtonPushed(app, event)
%%充電
try 
    assemblyCheck = NET.addAssembly('Ivi.Visa');
catch
    error('Error loading .NET assembly Ivi.Visa');
end

resourceString5 = 'ASRL3::INSTR'; % USB-TMC (Test and Measurement Class)
s = serialport("COM4", 9600); % arduino

% Opening VISA session to the instrument
scope = Ivi.Visa.GlobalResourceManager.Open(resourceString5);
scope.Clear()

%% 開啟GPP-3323電子負載器模式並設定汲取電流
WRVI_load(scope,2);
pause(2);

%% 初始化數組以記錄數據
% Initialize record arrays
voltage_load = [];
current_load = [];
temprature = [];
time = [];

%% 開始充電並記錄數據
start_time = tic; % the timer begins
while true
    [v_l, i_l] = RDVI_load(scope);
    temp = Temp(s);
    elapsed_time = toc(start_time);
    voltage_load = [voltage_load; str2double(v_l)];
    current_load = [current_load; str2double(i_l)];
    temprature = [temprature; str2double(temp)];
    time = [time; toc(start_time);];
    soc = ((str2double(v_l)-2.7)/(4.2-2.7))*100;
    set(app.SOCLabel,'text',["電池電量:"+num2str(soc)+"%"]);

    if str2double(v_l) >= 1.9800
        capacity_value = str2double(i_l).*time(end)./3600;
        capacity = ones(size(voltage_load)) * capacity_value;
        disp('charging is complete');
        set(app.Label_2,'text',"充電完成");
        off_ch1(scope);
        off_ch2(scope);
        break;
    end
    pause(6);
end
data = [capacity, voltage_load, -1.*current_load, temprature, time];
data_table = table(capacity, voltage_load, -1.*current_load, temprature, time, ...
    'VariableNames', {'Capacity', 'Voltage_Load', 'Current_Load', 'Temperature', 'Time'});
writetable(data_table,'Cycle5.csv');

        end

        % Button pushed function: dischargeButton
        function dischargeButtonPushed(app, event)
 %% 連接NI-VISA控制GPP-3323

load('lstm_model.mat');


try 
    assemblyCheck = NET.addAssembly('Ivi.Visa');
catch
    error('Error loading .NET assembly Ivi.Visa');
end

resourceString5 = 'ASRL3::INSTR'; % USB-TMC (Test and Measurement Class)
s = serialport("COM4", 9600); % arduino
%w = serialport("COM5", 9600); % arduino
% Opening VISA session to the instrument
scope = Ivi.Visa.GlobalResourceManager.Open(resourceString5);
scope.Clear()

%% 開啟GPP-3323電子負載器模式並設定汲取電流
WRVI_load(scope,2);
pause(2);

%% 初始化數組以記錄數據
% Initialize record arrays
voltage_load = [];
current_load = [];
temprature = [];
time = [];

predicted_capacity_m = [];
predicted_soh_m = [];
data_for_predict = [];
cycle = 6;
max_capacity = 1.239;
capacity_1 = 1.239;
capacity_2 = 1.213;
capacity_3 = 1.132;
capacity_4 = 1.109;
capacity_5 = 1.061;


%% 開始充電並記錄數據
start_time = tic; % the timer begins
while true
    [v_l, i_l] = RDVI_load(scope);
    temp = Temp(s);
    elapsed_time = toc(start_time);

    voltage_load = [voltage_load; str2double(v_l)];
    soc = ((str2double(v_l)-2.7)/(4-2.7))*100

    current_load = [current_load; -1.*str2double(i_l)];
    temprature = [temprature; str2double(temp)];
    %顯示提取溫度
    set(app.temperatureLabel,'text',["電池溫度:"+temp+"度"]);
    %溫度過高提醒
    if str2num(temp)>25
        set(app.Label,'text',"電池溫度過高");
    end

    time = [time; toc(start_time);];


    data_for_predict = [cycle, capacity_1, capacity_2, capacity_3 ,capacity_4, capacity_5, ...
    str2double(temp), str2double(i_l), str2double(v_l), toc(start_time);]

    predicted_soh = predict(lstm_model, data_for_predict')
    predicted_soh_m = [predicted_soh_m; predicted_soh];

    predicted_capacity = predicted_soh*max_capacity
    predicted_capacity_m = [predicted_capacity_m; predicted_capacity];
   
    %LCD(w,temprature,predicted_soh,soc);
    %設定顯示資料
    set(app.SOHLabel,'text',["電池健康度:"+num2str(predicted_soh*100)+"%"]);
    set(app.SOCLabel,'text',["電池電量:"+num2str(soc)+"%"]);
    
    if str2double(v_l) <= 2.7000
        capacity_value = str2double(i_l).*time(end)./3600;
        
        cycle = cycle + 1;
        capacity_1 = capacity_2;
        capacity_2 = capacity_3;
        capacity_3 = capacity_4;
        capacity_4 = capacity_5;
        capacity_5 = capacity_value;
        %data_for_predict = [cycle, capacity_1, capacity_2, capacity_3 ,capacity_4, capacity_5,temprature, current_load, voltage_load, time];

        
        capacity = ones(size(voltage_load)) * capacity_value;
        disp('discharging is complete');
        off_ch1(scope);
        off_ch2(scope);
        %顯示放電完成請充電
        set(app.Label_3,'text',"請充電");
        break;
    end
    pause(6);
end
data = [capacity, voltage_load, current_load, temprature, time];
data_table = table(capacity, voltage_load, current_load, temprature, time, ...
    'VariableNames', {'Capacity', 'Voltage_Load', 'Current_Load', 'Temperature', 'Time'});
writetable(data_table,'Cycle5.csv');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SOHLabel = uilabel(app.UIFigure);
            app.SOHLabel.Text = 'SOH';
            app.SOCLabel = uilabel(app.UIFigure);
            app.SOCLabel.Text = 'SOC';
            app.temperatureLabel = uilabel(app.UIFigure);
            app.temperatureLabel.Text = 'temperature';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.chargeButton = uibutton(app.UIFigure, 'push');
            app.chargeButton.ButtonPushedFcn = createCallbackFcn(app, @chargeButtonPushed, true);
            app.chargeButton.Text = 'charge';
            app.dischargeButton = uibutton(app.UIFigure, 'push');
            app.dischargeButton.ButtonPushedFcn = createCallbackFcn(app, @dischargeButtonPushed, true);
            app.dischargeButton.Text = 'discharge';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'coding101', 'app主視覺', '開始按鈕.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end